classdef EasyPlotModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyPlotModuleUIFigure          matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        ygridButton                     matlab.ui.control.Button
        xgridButton                     matlab.ui.control.Button
        ylinlogButton                   matlab.ui.control.Button
        xlinlogButton                   matlab.ui.control.Button
        ReplaceStringinNameButton       matlab.ui.control.Button
        ExportStyleButton               matlab.ui.control.Button
        ReadStyleButton                 matlab.ui.control.Button
        FlipSelectionButton             matlab.ui.control.Button
        FlipAllButton                   matlab.ui.control.Button
        MoveDownButton                  matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        MergeSelectionButton            matlab.ui.control.Button
        CloseallfiguresButton           matlab.ui.control.Button
        ApplyGlobaltoselectionButton    matlab.ui.control.Button
        ApplyGlobalButton               matlab.ui.control.Button
        YLimitsEditField_min            matlab.ui.control.NumericEditField
        YLimitsEditFieldLabel           matlab.ui.control.Label
        YLimitsEditField_max            matlab.ui.control.NumericEditField
        XLimitsEditField_max            matlab.ui.control.NumericEditField
        XLimitsEditField_min            matlab.ui.control.NumericEditField
        XLimitsEditFieldLabel           matlab.ui.control.Label
        CustomAxLimitsCheckBox          matlab.ui.control.CheckBox
        UpdateListButton                matlab.ui.control.Button
        Tree                            matlab.ui.container.Tree
        TabGroup                        matlab.ui.container.TabGroup
        PlotSettingsTab                 matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        FindPlotButton                  matlab.ui.control.Button
        LegendInterpreterDropDown       matlab.ui.control.DropDown
        LegendInterpreterDropDownLabel  matlab.ui.control.Label
        ShowLegendCheckBox              matlab.ui.control.CheckBox
        RemoveSelectedButton            matlab.ui.control.Button
        AllSettingsCheckBox             matlab.ui.control.CheckBox
        ColorPreviewCheckBox            matlab.ui.control.CheckBox
        MultiCor_table                  matlab.ui.control.Table
        ExportTab                       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        CustStrEditField                matlab.ui.control.EditField
        CustomStringLabel               matlab.ui.control.Label
        CheckBox_fig_exp                matlab.ui.control.CheckBox
        CheckBox_jpg_exp                matlab.ui.control.CheckBox
        ExportSelectionButton           matlab.ui.control.Button
        ExportSelectedButton            matlab.ui.control.Button
        CheckBox_tif_exp                matlab.ui.control.CheckBox
        CheckBox_eps_exp                matlab.ui.control.CheckBox
        CheckBox_pdf_exp                matlab.ui.control.CheckBox
        CheckBox_png_exp                matlab.ui.control.CheckBox
        ExportDirectoryEditField        matlab.ui.control.EditField
        ExportDirectoryEditFieldLabel   matlab.ui.control.Label
        SelectDirButton                 matlab.ui.control.Button
        ExportAllButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        MinPlotX
        active_plot % Description
        click_time % Description
    end

    methods (Access = private)

        function ResetTree(app,Tree2Update)
            if not(exist('Tree2Update','var'))
                Tree2Update=app.Tree;
            end

            %             if ~isempty(Tree2Update.SelectedNodes)
            %                 app.SelectedNodes_MainTree = Tree2Update.SelectedNodes.NodeData;
            %             end

            a = Tree2Update.Children;
            a.delete;
        end


        function update_ScatterObject_table(app)
            T=table();

            if isempty(app.MinPlotX.MinPlotXFigures.hFig)%;(app.active_plot)
                app.MultiCor_table.Data=T;
                return
            end

            scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));

            if isempty(scatter_obj)
                app.MultiCor_table.Data=T;
                return
            end

            Name={scatter_obj.DisplayName}';%app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).legend_str';


            if any(cellfun(@ischar,{scatter_obj.MarkerFaceColor}))
                MarkerFaceColor={scatter_obj.MarkerFaceColor}';
            else
                MarkerFaceColor=cell2mat({scatter_obj.MarkerFaceColor}');
            end

            %             if ischar(MarkerFaceColor)
            %                 MarkerFaceColor={scatter_obj.MarkerFaceColor}';
            %             end

            Symbol={scatter_obj.Marker}';
            SymbolSize=cell2mat({scatter_obj.SizeData}');
            Active=cellfun(@logical,{scatter_obj.Visible}');


            try
                if app.AllSettingsCheckBox.Value==true

                    MarkerEdgeColor=cell2mat({scatter_obj.MarkerEdgeColor}');


                    MarkerEdgeAlpha=cell2mat({scatter_obj.MarkerEdgeAlpha}');
                    MarkerFaceAlpha=cell2mat({scatter_obj.MarkerFaceAlpha}');

                    LineWidth=cell2mat({scatter_obj.LineWidth}');


                    T=table(Active,Name,Symbol,SymbolSize,MarkerFaceColor,MarkerFaceAlpha,MarkerEdgeColor,MarkerEdgeAlpha,LineWidth);%here
                    %C={Active,Name,Symbol,SymbolSize,MarkerFaceColor,MarkerFaceAlpha,MarkerEdgeColor,MarkerEdgeAlpha,LineWidth};

                    %  C=[Name,Name,Symbol num2cell(SymbolSize),num2cell(MarkerFaceColor) ,num2cell(MarkerFaceAlpha) ,num2cell(MarkerEdgeColor) ,num2cell(MarkerEdgeAlpha)];
                else
                    T=table(Active,Name,Symbol,SymbolSize,MarkerFaceColor);%here

                    %  C=[Name,Name,Symbol num2cell(SymbolSize),num2cell(MarkerFaceColor)];

                end


                if app.AllSettingsCheckBox.Value==true
                    T.Properties.VariableNames={'Active'; 'Name'; 'Symbol'; 'SymbolSize';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth'};
                    c_editable=[true,true,true,true,false,true,false,true,true];
                else
                    T.Properties.VariableNames={'Active'; 'Name'; 'Symbol'; 'SymbolSize';'MarkerFaceColor'};
                    c_editable=[true,true,true,true,false];

                end


                app.MultiCor_table.Data=T;

                app.MultiCor_table.ColumnName=T.Properties.VariableNames;
                app.MultiCor_table.ColumnEditable=c_editable;


                %             app.MultiCor_table.ColumnFormat=[];
                %                 app.MultiCor_table.ColumnFormat(1,1:size(T,2))={[]};

                %app.MultiCor_table.ColumnFormat(ismember(app.MultiCor_table.ColumnName,'Symbol'))={cellstr(["o","+","*",".","x","_","|","square","diamond","^","v",">","<","pentagram","hexagram"]')'}; % here change to function names
                %app.MultiCor_table.ColumnFormat(1)={'logical'};


                if app.ColorPreviewCheckBox.Value==true && not(isempty(T))
                    for n=1:numel(app.MultiCor_table.Data.Active)
                        if isnumeric(app.MultiCor_table.Data.MarkerFaceColor(n,:))
                            s = uistyle('BackgroundColor',app.MultiCor_table.Data.MarkerFaceColor(n,:));
                            addStyle(app.MultiCor_table,s,'cell',[n,5])
                        end
                    end
                    %                 s = uistyle('BackgroundColor',[1 1 1 ]);
                    %                 addStyle(app.MultiCor_table,s,'column',[1:5 7])

                    if app.AllSettingsCheckBox.Value==true
                        for n=1:numel(app.MultiCor_table.Data.Active)
                            s = uistyle('BackgroundColor',app.MultiCor_table.Data.MarkerEdgeColor(n,:));
                            addStyle(app.MultiCor_table,s,'cell',[n,7])
                        end
                    end
                else
                    s = uistyle('BackgroundColor',[1 1 1 ]);

                    addStyle(app.MultiCor_table,s,'column',1:size(app.MultiCor_table.Data,2))
                end
            catch ME
                DisplayErrorNG(app.MinPlotX,ME,'EasyPlotModule Compile Table Error')
            end

        end

        function FileName=CheckFileName(~,FileName,path)

            if  exist(fullfile(path,FileName),'file')>0
                d=dir(path);
                [~, n, ext]=fileparts(FileName);

                [~, nms, cc]=fileparts({d.name});

                FileName=matlab.lang.makeUniqueStrings([nms(ismember(cc,ext)),{n}]);
                FileName=[FileName{end} ext] ;
            end
        end


        function update_fig_limits(app)
            xlim=      app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim;
            ylim=      app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim;
            app.XLimitsEditField_min.Value=xlim(1);
            app.XLimitsEditField_max.Value=xlim(2);
            app.YLimitsEditField_min.Value=ylim(1);
            app.YLimitsEditField_max.Value=ylim(2);
        end
    end

    methods (Access = public)

        function MinPlotXEasyPlot_public_update(app)
            Tree2Update=app.Tree;
            ResetTree(app,Tree2Update);

            try
                if isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')&& not(isempty(app.MinPlotX.MinPlotXFigures.hFig_type))
                    app.MinPlotX.MinPlotXFigures.hFig_type=app.MinPlotX.MinPlotXFigures.hFig_type(cellfun(@isgraphics,{app.MinPlotX.MinPlotXFigures.hFig.ax2plot}));
                    app.MinPlotX.MinPlotXFigures.hFig_description=app.MinPlotX.MinPlotXFigures.hFig_description(cellfun(@isgraphics,{app.MinPlotX.MinPlotXFigures.hFig.ax2plot}));
                    app.MinPlotX.MinPlotXFigures.hFig=app.MinPlotX.MinPlotXFigures.hFig(cellfun(@isgraphics,{app.MinPlotX.MinPlotXFigures.hFig.ax2plot}));
                else
                    app.MinPlotX.MinPlotXFigures.hFig_type=[];
                    app.MinPlotX.MinPlotXFigures.hFig_description=[];
                    app.MinPlotX.MinPlotXFigures.hFig=[];
                end
            catch ME
                DisplayErrorNG(app.MinPlotX,ME,'EasyPlotModule Public Update Error')
                app.MinPlotX.MinPlotXFigures.hFig_type=[];
                app.MinPlotX.MinPlotXFigures.hFig_description=[];
                app.MinPlotX.MinPlotXFigures.hFig=[];

            end


            hFig_type=app.MinPlotX.MinPlotXFigures.hFig_type;
            hFig_description=app.MinPlotX.MinPlotXFigures.hFig_description;
            for n=1:numel(hFig_type)
                uitreenode(Tree2Update,'Text',string(hFig_description{n}),'NodeData',hFig_type{n});
            end

            if isempty(app.active_plot) ||  numel(hFig_type)<app.active_plot
                app.active_plot=1;
            end
            update_ScatterObject_table(app)
        end

        function ExportFigureFun(app,n)

            path=app.ExportDirectoryEditField.Value;

            if not(strcmp(app.Tree.Children(n).NodeData,app.MinPlotX.MinPlotXFigures.hFig_type{n}))
                MinPlotXEasyPlot_public_update(app)
            end

            plot_name=app.MinPlotX.MinPlotXFigures.hFig_type{n};
            if not(isempty(app.CustStrEditField.Value)) && not(endsWith(app.CustStrEditField.Value,'_'))
                FileName=[ app.CustStrEditField.Value '_' plot_name];
            elseif not(isempty(app.CustStrEditField.Value)) && (endsWith(app.CustStrEditField.Value,'_'))
                FileName=[ app.CustStrEditField.Value plot_name];
            else
                FileName=plot_name;

            end
            hFig=app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot(1).Parent(1).Parent(1);


            if app.CheckBox_fig_exp.Value==true
                FileName_=CheckFileName(app,[FileName '.fig'],path);
                savefig(hFig,fullfile(path,FileName_))
            end
            if app.CheckBox_eps_exp.Value==true
                FileName_=CheckFileName(app,[FileName '.eps'],path);
                exportgraphics(hFig,fullfile(path,FileName_),'BackgroundColor','none','ContentType','vector')
            end
            if app.CheckBox_png_exp.Value==true
                FileName_=CheckFileName(app,[FileName '.png'],path);
                exportgraphics(hFig,fullfile(path,FileName_),Resolution=300)
            end
            if app.CheckBox_tif_exp.Value==true
                FileName_=CheckFileName(app,[FileName '.tif'],path);
                exportgraphics(hFig,fullfile(path,FileName_),Resolution=1200)
            end
            if app.CheckBox_jpg_exp.Value==true
                FileName_=CheckFileName(app,[FileName '.jpg'],path);
                exportgraphics(hFig,fullfile(path,FileName_))
            end

            if app.CheckBox_pdf_exp.Value==true
                FileName_=CheckFileName(app,[FileName '.pdf'],path);
                exportgraphics(hFig,fullfile(path,FileName_),'ContentType','vector')
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MinPlotX=mainapp;
            UpdateListButtonPushed(app)

            if isempty(app.ExportDirectoryEditField.Value)
                app.ExportDirectoryEditField.Value=app.MinPlotX.WorkingDirectoryEditField.Value;
            end

        end

        % Button pushed function: RemoveSelectedButton
        function RemoveSelectedButtonPushed(app, event)

            if isempty(app.MultiCor_table.Selection)
                return
            end

            scatter_Id=unique(app.MultiCor_table.Selection(:,1));
            scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));
            thx=ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj(scatter_Id));
            delete(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(thx))


            update_ScatterObject_table(app)

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)

            MinPlotXEasyPlot_public_update(app)
        end

        % Value changed function: AllSettingsCheckBox
        function AllSettingsCheckBoxValueChanged(app, event)
            update_ScatterObject_table(app)

            if app.AllSettingsCheckBox.Value==true
                state=true;
            else
                state=false;
            end
            app.CustomAxLimitsCheckBox.Visible=state;
            app.ApplyGlobalButton.Visible=state;
            app.MergeSelectionButton.Visible=state;
            app.ApplyGlobaltoselectionButton.Visible=state;
            app.MoveDownButton.Visible=state;
            app.MoveUpButton.Visible=state;
            app.FlipAllButton.Visible=state;
            app.FlipSelectionButton.Visible=state;

            app.ReadStyleButton.Visible=state;
            app.ExportStyleButton.Visible=state;
            app.ReplaceStringinNameButton.Visible=state;


            app.xlinlogButton.Visible=state;
            app.ylinlogButton.Visible=state;
            app.xgridButton.Visible=state;
            app.ygridButton.Visible=state;

            app.XLimitsEditField_min.Visible=state &&app.CustomAxLimitsCheckBox.Value;
            app.XLimitsEditField_max.Visible=state&&app.CustomAxLimitsCheckBox.Value;
            app.YLimitsEditField_min.Visible=state&&app.CustomAxLimitsCheckBox.Value;
            app.YLimitsEditField_max.Visible=state&&app.CustomAxLimitsCheckBox.Value;
            app.XLimitsEditFieldLabel.Visible=state&&app.CustomAxLimitsCheckBox.Value;
            app.YLimitsEditFieldLabel.Visible=state&&app.CustomAxLimitsCheckBox.Value;


        end

        % Value changed function: ColorPreviewCheckBox
        function ColorPreviewCheckBoxValueChanged(app, event)
            update_ScatterObject_table(app)

        end

        % Cell edit callback: MultiCor_table
        function MultiCor_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            scatter_Id=indices(1);
            scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));

            switch app.MultiCor_table.ColumnName{indices(2)}
                case 'SymbolSize'
                    if not(isnumeric(newData)) || newData<0
                        message={'Value must be larger than 0!'};
                        uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                        update_ScatterObject_table(app)
                        return
                    end
                    scatter_obj(scatter_Id).SizeData=newData;
                case {'MarkerFaceAlpha','MarkerEdgeAlpha'}
                    if not(isnumeric(newData)) || newData>1 ||newData<0
                        message={'Value must be between 0 and 1!'};
                        uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                        update_ScatterObject_table(app)
                        return
                    end
                    scatter_obj(scatter_Id).(char(app.MultiCor_table.ColumnName(indices(2))))=newData;

                case 'Symbol'
                    %panelty","^","v",">","<","","hexagram
                    if not(ismember(newData,{'square','o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'}))
                        message={'Only the following options are allowed: ','''square'',''diamond'',''pentagram'',''hexagram'',''o'', ''+'', ''*'', ''.'', ''x'', ''_'', ''|'', ''s'', ''d'', ''^'', ''v'', ''>'', ''<'', ''p'', ''h'', ''none'''};
                        uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                        update_ScatterObject_table(app)
                        return
                    end
                    scatter_obj(scatter_Id).Marker=newData;
                case 'Active'
                    %panelty
                    scatter_obj(scatter_Id).Visible=newData;
                case 'Name'
                    scatter_obj(scatter_Id).DisplayName=newData;
                otherwise
                    scatter_obj(scatter_Id).(char(app.MultiCor_table.ColumnName(indices(2))))=newData;
            end

        end

        % Button down function: MultiCor_table
        function MultiCor_tableButtonDown(app, event)
            click_threshold_s = 0.5;
            if isempty(app.click_time)
                app.click_time = tic();
            else

                time_between_clicks = toc(app.click_time );
                app.click_time = tic();
                if time_between_clicks < click_threshold_s
                    % if field==Color
                    if isempty(app.MultiCor_table.Selection)|| not(any(ismember(unique(app.MultiCor_table.Selection(:,2)),5)) || any(ismember(unique(app.MultiCor_table.Selection(:,2)),7)))
                        return
                    end
                    if any(ismember(unique(app.MultiCor_table.Selection(:,2)),5)) && endsWith(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).options.type.Value,'XYZ')
                        selection = uiconfirm(app.EasyPlotModuleUIFigure,'Do you want to change the XYZ Plot MarkerFaceColor','XYZ options', ...
                            'Options',{'Use Single Color','Use Colormap','Cancel'}, ...
                            'DefaultOption',2,'CancelOption',3);
                        if strcmp(selection,'Cancel')
                            return
                        elseif strcmp(selection,'Use Single Color')
                            c = uisetcolor;  % Enter desired callback / action here.
                            figure(app.EasyPlotModuleUIFigure)
                            if c==0
                                return
                            end
                        elseif strcmp(selection,'Use Colormap')
                            c='flat';

                        end

                    else
                        c = uisetcolor;  % Enter desired callback / action here.
                        figure(app.EasyPlotModuleUIFigure)
                        if c==0
                            return
                        end
                    end

                    scatter_Id=unique(app.MultiCor_table.Selection(:,1));
                    scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));

                    if any(ismember(unique(app.MultiCor_table.Selection(:,2)),5))
                        [scatter_obj(scatter_Id).MarkerFaceColor]=deal(c);
                    elseif any(ismember(unique(app.MultiCor_table.Selection(:,2)),7))
                        [scatter_obj(scatter_Id).MarkerEdgeColor]=deal(c);

                    end
                    update_ScatterObject_table(app)

                    %  end
                end % if double-click
            end % if empty

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

            if isempty(app.active_plot) || numel(app.MinPlotX.MinPlotXFigures.hFig_type)<app.active_plot || not(isgraphics(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot))
                UpdateListButtonPushed(app)
                return
            end
            update_ScatterObject_table(app)
            app.active_plot=find(strcmp(app.Tree.SelectedNodes.NodeData,{app.Tree.Children.NodeData}));


            update_ScatterObject_table(app)


            if ~isempty(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Legend)
                app.LegendInterpreterDropDown.Value=app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Legend.Interpreter;
                app.ShowLegendCheckBox.Value=app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Legend.Visible;

            else
                app.LegendInterpreterDropDown.Value='none';
                app.ShowLegendCheckBox.Value=false;
            end

            xlim=      app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim;
            ylim=      app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim;
            app.XLimitsEditField_min.Value=xlim(1);
            app.XLimitsEditField_max.Value=xlim(2);
            app.YLimitsEditField_min.Value=ylim(1);
            app.YLimitsEditField_max.Value=ylim(2);


        end

        % Value changed function: LegendInterpreterDropDown
        function LegendInterpreterDropDownValueChanged(app, event)
            app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Legend.Interpreter = app.LegendInterpreterDropDown.Value;
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Legend.Visible=app.ShowLegendCheckBox.Value;

            % [h,icons]=legend(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Legend.String);
            % h = findobj(icons,'type','patch');
            % set(h,'MarkerSize',11);
        end

        % Button pushed function: FindPlotButton
        function FindPlotButtonPushed(app, event)
            try
                figure(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot(1).Parent(1).Parent(1))
            catch
                MinPlotXEasyPlot_public_update(app)
            end
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir(pwd,'select working directory with read/write permission');
            delete(f)

            if ~isequal(path,0)
                app.ExportDirectoryEditField.Value=path;
            end
            figure(app.EasyPlotModuleUIFigure)
        end

        % Button pushed function: ExportSelectedButton
        function ExportSelectedButtonPushed(app, event)
            if not(isfield(app.MinPlotX,'MinPlotXFigures')) || isempty(app.MinPlotX.MinPlotXFigures) ||not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type)
                return
            end
            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Export...',...
                'Message','Exporting Plot','Cancelable','off');

            ExportFigureFun(app,app.active_plot)
            delete(d)
        end

        % Button pushed function: ExportAllButton
        function ExportAllButtonPushed(app, event)
            MinPlotXEasyPlot_public_update(app)
            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Export...',...
                'Message','Export Started','Cancelable','on');

            for n=1:numel(app.MinPlotX.MinPlotXFigures.hFig_type)
                d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig_type);
                d.Message = sprintf(['Exporting Plot: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig_type)) ': '   app.MinPlotX.MinPlotXFigures.hFig_type{n} ]);
                ExportFigureFun(app,n)
            end
        end

        % Button pushed function: ExportSelectionButton
        function ExportSelectionButtonPushed(app, event)

            MinPlotXEasyPlot_public_update(app)
            if not(isfield(app.MinPlotX,'MinPlotXFigures')) || isempty(app.MinPlotX.MinPlotXFigures) ||not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type)
                return
            end

            [indx,~] = listdlg('PromptString',{'Select Groups'},...
                'SelectionMode','multiple','ListString',app.MinPlotX.MinPlotXFigures.hFig_type,'InitialValue',...
                app.active_plot);
            figure(app.EasyPlotModuleUIFigure);

            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Export...',...
                'Message','Export Started','Cancelable','on');

            for n=1:numel(indx)
                d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig_type);
                d.Message = sprintf(['Exporting Plot: ' num2str(n) ' of ' num2str(numel(indx)) ': '   app.MinPlotX.MinPlotXFigures.hFig_type{indx(n)} ]);
                ExportFigureFun(app,indx(n))
            end
        end

        % Value changed function: CustomAxLimitsCheckBox
        function CustomAxLimitsCheckBoxValueChanged(app, event)
            value = app.CustomAxLimitsCheckBox.Value;

            if value==true
                state=true;
                xlim=      app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim;
                ylim=      app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim;
                app.XLimitsEditField_min.Value=xlim(1);
                app.XLimitsEditField_max.Value=xlim(2);
                app.YLimitsEditField_min.Value=ylim(1);
                app.YLimitsEditField_max.Value=ylim(2);
            else
                state=false;
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLimMode='auto';
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLimMode='auto';
            end

            app.XLimitsEditField_min.Visible=state;
            app.XLimitsEditField_max.Visible=state;
            app.YLimitsEditField_min.Visible=state;
            app.YLimitsEditField_max.Visible=state;
            app.XLimitsEditFieldLabel.Visible=state;
            app.YLimitsEditFieldLabel.Visible=state;

            update_fig_limits(app)

        end

        % Value changed function: XLimitsEditField_max, 
        % XLimitsEditField_min, YLimitsEditField_max, YLimitsEditField_min
        function XLimitsEditField_minValueChanged(app, event)
            value = app.XLimitsEditField_min.Value;


            try
                xlim(1)=app.XLimitsEditField_min.Value;
                xlim(2)=app.XLimitsEditField_max.Value;
                ylim(1)=app.YLimitsEditField_min.Value;
                ylim(2)=app.YLimitsEditField_max.Value;

                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim=sort(xlim);
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim=sort(ylim);
            catch ME
                DisplayErrorNG(app.MinPlotX,ME,'XLimitsEditField Update Error')
                CustomAxLimitsCheckBoxValueChanged(app)
            end

        end

        % Button pushed function: ApplyGlobalButton
        function ApplyGlobalMenuSelected(app, event)
            % ATH add if no line is selected use all
            % ATH optional add ask if multiple entries should be updated

            scatter_Id_org=unique(app.MultiCor_table.Selection(:,1));

            if numel(scatter_Id_org)>2 && numel(app.MultiCor_table.Data.Name(scatter_Id_org))>numel(unique(app.MultiCor_table.Data.Name(scatter_Id_org)))
                message={'Select objects with unique names!'};
                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                return
            end

            for n_SID=1:numel(scatter_Id_org)
                scatter_obj_org=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));

                Name=app.MultiCor_table.Data.Name(scatter_Id_org(n_SID));

                d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Applying Changes...',...
                    'Message','Progress Started','Cancelable','on');
                w2c={'Visible'; 'Marker'; 'SizeData';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth';'legend'};

                for n=1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                    d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                    d.Message = sprintf(['Changing appearance in figure: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig))]);
                    if d.CancelRequested
                        break
                    end

                    try

                        if app.active_plot==n

                        else
                            scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot,'Type','Scatter'));

                            if any(strcmp({scatter_obj.DisplayName},Name))
                                scatter_Id=find(strcmp({scatter_obj.DisplayName},Name));

                                for l=1:numel(scatter_Id)
                                    for k=1:numel(w2c)
                                        if strcmp(w2c(k),'legend')
                                            scatter_obj(scatter_Id(l)).Parent.Legend.Interpreter=scatter_obj_org(scatter_Id_org(n_SID)).Parent.Legend.Interpreter;
                                            scatter_obj(scatter_Id(l)).Parent.Legend.Visible=scatter_obj_org(scatter_Id_org(n_SID)).Parent.Legend.Visible;
                                        else
                                            scatter_obj(scatter_Id(l)).(char(w2c(k)))=scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));
                                        end
                                    end
                                end
                            end
                        end
                    catch
                        0
                    end
                end
            end
        end

        % Button pushed function: ApplyGlobaltoselectionButton
        function ApplyGlobaltoselectionMenuSelected(app, event)
            scatter_Id_org=unique(app.MultiCor_table.Selection(:,1));
            if numel(scatter_Id_org)==1
                scatter_obj_org=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));
                d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Generating List Of Items',...
                    'Message','Progress Started','Cancelable','on');

                for n=1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                    d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                    if d.CancelRequested
                        break
                    end


                    try
                        scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot,'Type','Scatter'));
                        if not(isempty(scatter_obj))
                            if not(exist('Name','var'))
                                Name={scatter_obj.DisplayName};
                            else

                                Name=unique([{scatter_obj.DisplayName},Name]);
                                d.Message = sprintf([num2str(numel(Name)) ' Objects detected']);
                            end
                        end
                    catch ME
                        DisplayErrorNG(app.MinPlotX,ME,'Apply Global to Selection Error 01')

                    end
                end
            end

            if not(exist('Name','var'))
                return
            else
                list2=Name;
                [indx,~] = listdlg('ListString',list2,'SelectionMode','multi');
                figure(app.EasyPlotModuleUIFigure);
                Name=list2(indx);
                if isempty(Name)
                    return
                end
            end

            answer = uiconfirm(app.EasyPlotModuleUIFigure, ...
                ['Do you want to update the name for all selected objects?'],"Update Name", ...
                "Icon","warning","Options",["Yes","No","Cancel"]);

            if strcmp(answer,'Cancel')
                return
            elseif strcmp(answer,'Yes')
                w2c={'DisplayName';'Visible'; 'Marker'; 'SizeData';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth';'legend'};
            elseif strcmp(answer,'No')
                w2c={'Visible'; 'Marker'; 'SizeData';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth';'legend'};

            end

            for n_SID=1:numel(Name)

                d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Applying Changes...',...
                    'Message','Progress Started','Cancelable','on');

                for n=1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                    d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                    d.Message = sprintf(['Changing appearance in figure: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig))]);
                    if d.CancelRequested
                        break
                    end

                    try

                        if app.active_plot==n

                        else
                            scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot,'Type','Scatter'));


                            if not(isempty(scatter_obj))&& any(strcmp({scatter_obj.DisplayName},Name{n_SID}))
                                scatter_Id=find(strcmp({scatter_obj.DisplayName},Name{n_SID}));

                                for l=1:numel(scatter_Id)
                                    for k=1:numel(w2c)
                                        if strcmp(w2c(k),'legend')
                                            scatter_obj(scatter_Id(l)).Parent.Legend.Interpreter=scatter_obj_org(scatter_Id_org).Parent.Legend.Interpreter;
                                            scatter_obj(scatter_Id(l)).Parent.Legend.Visible=scatter_obj_org(scatter_Id_org).Parent.Legend.Visible;
                                        else
                                            scatter_obj(scatter_Id(l)).(char(w2c(k)))=scatter_obj_org(scatter_Id_org).(char(w2c(k)));
                                        end
                                    end
                                end
                            end
                        end
                    catch ME
                        DisplayErrorNG(app.MinPlotX,ME,'Apply Global to Selection Error 02')

                    end

                end
            end

        end

        % Button pushed function: CloseallfiguresButton
        function CloseallfiguresButtonPushed(app, event)
            for n=1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                delete(app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot(1).Parent(1).Parent(1))
            end

            MinPlotXEasyPlot_public_update(app)
        end

        % Button pushed function: MergeSelectionButton
        function MergeSelectionButtonPushed(app, event)
            scatter_Id_org=unique(app.MultiCor_table.Selection(:,1));

            if numel(scatter_Id_org)>2 && numel(app.MultiCor_table.Data.Name(scatter_Id_org))>numel(unique(app.MultiCor_table.Data.Name(scatter_Id_org)))
                message={'Select objects with unique names!'};
                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                return
            end

            scatter_obj_org=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));

            if numel(unique({scatter_obj_org(scatter_Id_org).Marker}))==1 && numel(unique(cell2mat({scatter_obj_org(scatter_Id_org).SizeData}'),'rows'))==1&&...
                    numel(unique(cell2mat({scatter_obj_org(scatter_Id_org).MarkerFaceColor}'),'rows'))==3 && numel(unique(cell2mat({scatter_obj_org(scatter_Id_org).MarkerFaceAlpha}'),'rows'))==1 &&...
                    numel(unique(cell2mat({scatter_obj_org(scatter_Id_org).MarkerEdgeColor}'),'rows'))==3 && numel(unique(cell2mat({scatter_obj_org(scatter_Id_org).MarkerEdgeAlpha}'),'rows'))==1 &&...
                    numel(unique(cell2mat({scatter_obj_org(scatter_Id_org).LineWidth}'),'rows'))==1

                target_obj=scatter_Id_org(1);
            else
                list2=app.MultiCor_table.Data.Name(scatter_Id_org);
                [indx,~] = listdlg('PromptString',{'Which entry should be the target?'},'ListString',list2,'SelectionMode','multi');

                target_obj=scatter_Id_org(indx);
            end

            for n_SID=1:numel(scatter_Id_org)
                if not(scatter_Id_org(n_SID)==target_obj)

                    scatter_obj_org(target_obj).XData=[scatter_obj_org(target_obj).XData scatter_obj_org(scatter_Id_org(n_SID)).XData];
                    scatter_obj_org(target_obj).YData=[scatter_obj_org(target_obj).YData scatter_obj_org(scatter_Id_org(n_SID)).YData];
                    scatter_obj_org(target_obj).ZData=[scatter_obj_org(target_obj).ZData scatter_obj_org(scatter_Id_org(n_SID)).ZData];
                    %% add x and y values to selected.
                    %% remove empty ones

                end
            end
            delete(scatter_obj_org(scatter_Id_org(not(scatter_Id_org==target_obj))))
            update_ScatterObject_table(app)
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            scatter_Id_org=unique(app.MultiCor_table.Selection(:,1));
            app.MultiCor_table.Selection(:,1)=sort(app.MultiCor_table.Selection(:,1));

            scatter_obj_org=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));

            sc_obj_nr_org=1:numel(scatter_obj_org);

            max_val=1;

            for n=1:numel(app.MultiCor_table.Selection(:,1))
                if app.MultiCor_table.Selection(n,1)>max_val

                    befor=sc_obj_nr_org(1:(scatter_Id_org(n)-1));

                    after=sc_obj_nr_org(scatter_Id_org(n)+1:size(app.MultiCor_table.Data,1));

                    %   data2sort([befor(1:end-1)  ids2sort  befor(end) after])=app.selected_sample;

                    sc_obj_nr_org=[befor(1:end-1)  sc_obj_nr_org(scatter_Id_org(n)) befor(end) after];
                    app.MultiCor_table.Selection(n,1)=app.MultiCor_table.Selection(n,1)-1;

                else
                    max_val=max_val+1;
                end
            end

            scatter_obj_org=scatter_obj_org(sc_obj_nr_org);


            app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj_org))=flipud(scatter_obj_org);
            update_ScatterObject_table(app)
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            scatter_Id_org=unique(app.MultiCor_table.Selection(:,1));
            app.MultiCor_table.Selection(:,1)=sort(app.MultiCor_table.Selection(:,1),'descend');

            scatter_obj_org=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));

            sc_obj_nr_org=1:numel(scatter_obj_org);

            max_val=size(app.MultiCor_table.Data,1);

            for n=numel(app.MultiCor_table.Selection(:,1)):-1:1
                if app.MultiCor_table.Selection(n,1)<max_val

                    befor=sc_obj_nr_org(1:(scatter_Id_org(n)-1));

                    after=sc_obj_nr_org(scatter_Id_org(n)+1:size(app.MultiCor_table.Data,1));

                    sc_obj_nr_org=[befor after(1) sc_obj_nr_org(scatter_Id_org(n)) after(2:end)];
                    app.MultiCor_table.Selection(n,1)=app.MultiCor_table.Selection(n,1)+1;

                else
                    max_val=max_val-1;
                end
            end

            scatter_obj_org=scatter_obj_org(sc_obj_nr_org);

            app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj_org))=flipud(scatter_obj_org);
            %     app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children=flipud(scatter_obj_org);

            update_ScatterObject_table(app)

        end

        % Button pushed function: FlipAllButton
        function FlipAllButtonPushed(app, event)

            scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));
            app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj))=(scatter_obj);

            update_ScatterObject_table(app)

        end

        % Button pushed function: FlipSelectionButton
        function FlipSelectionButtonPushed(app, event)
            scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));

            scatter_Id_org=unique(app.MultiCor_table.Selection(:,1));
            scatter_obj(scatter_Id_org)=flip(scatter_obj(scatter_Id_org));


            app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj))=flipud(scatter_obj);
            update_ScatterObject_table(app)

        end

        % Button pushed function: ExportStyleButton
        function ExportStyleButtonPushed(app, event)
            VarNames={'Active','Name','Symbol','SymbolSize','MarkerFaceColor_r','MarkerFaceColor_g','MarkerFaceColor_b','MarkerFaceAlpha','MarkerEdgeColor_r','MarkerEdgeColor_g','MarkerEdgeColor_b','MarkerEdgeAlpha','LineWidth'};

            T=table();

            if isempty(app.MinPlotX.MinPlotXFigures.hFig)%;(app.active_plot)
                app.MultiCor_table.Data=T;
                return
            end

            scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot,'Type','Scatter'));

            if isempty(scatter_obj)
                return
            end

            Name={scatter_obj.DisplayName}';%app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).legend_str';


            if any(cellfun(@ischar,{scatter_obj.MarkerFaceColor}))
                MarkerFaceColor={scatter_obj.MarkerFaceColor}';
            else
                MarkerFaceColor=cell2mat({scatter_obj.MarkerFaceColor}');
            end

            %             if ischar(MarkerFaceColor)
            %                 MarkerFaceColor={scatter_obj.MarkerFaceColor}';
            %             end

            Symbol={scatter_obj.Marker}';
            SymbolSize=cell2mat({scatter_obj.SizeData}');
            Active=cellfun(@logical,{scatter_obj.Visible}');


            MarkerEdgeColor=cell2mat({scatter_obj.MarkerEdgeColor}');


            MarkerEdgeAlpha=cell2mat({scatter_obj.MarkerEdgeAlpha}');
            MarkerFaceAlpha=cell2mat({scatter_obj.MarkerFaceAlpha}');

            LineWidth=cell2mat({scatter_obj.LineWidth}');


            T=table(Active,Name,Symbol,SymbolSize,MarkerFaceColor(:,1),MarkerFaceColor(:,2),MarkerFaceColor(:,3),MarkerFaceAlpha,MarkerEdgeColor(:,1),MarkerEdgeColor(:,2),MarkerEdgeColor(:,3),MarkerEdgeAlpha,LineWidth);%here
            %C={Active,Name,Symbol,SymbolSize,MarkerFaceColor,MarkerFaceAlpha,MarkerEdgeColor,MarkerEdgeAlpha,LineWidth};

            %  C=[Name,Name,Symbol num2cell(SymbolSize),num2cell(MarkerFaceColor) ,num2cell(MarkerFaceAlpha) ,num2cell(MarkerEdgeColor) ,num2cell(MarkerEdgeAlpha)];


            T.Properties.VariableNames=VarNames;

            folder = pwd;
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file, folder] = uiputfile(fullfile(folder, '*.csv'), 'Save Style Table');
            if file == 0
                return;
            end
            delete(f);
            figure(app.EasyPlotModuleUIFigure);
            [~, FileNameNoExt, ~] = fileparts(file);
            fullFileName = fullfile(folder, [FileNameNoExt, '.csv']);
            writetable(T, fullFileName);
        end

        % Button pushed function: ReadStyleButton
        function ReadStyleButtonPushed(app, event)

            %%
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.csv'},"MultiSelect","off", 'Import Style Table');
            delete(f);
            figure(app.EasyPlotModuleUIFigure);

            if isequal(file,0)
                return
            end

            T= readtable(fullfile(path,file));


            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Applying Changes...',...
                'Message','Progress Started','Cancelable','on');
            w2c={'Visible'; 'Marker'; 'SizeData';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth';'legend'};

            for n=1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                d.Message = sprintf(['Changing appearance in figure: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig))]);
                if d.CancelRequested
                    break
                end
                for n_SID=1:numel(T.Name)

                    Name=T.Name(n_SID);
                    try

                        scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot,'Type','Scatter'));
                        if isempty(scatter_obj)
                            continue
                        end
                        if any(strcmp({scatter_obj.DisplayName},Name))
                            scatter_Id=find(strcmp({scatter_obj.DisplayName},Name));

                            for l=1:numel(scatter_Id)

                                scatter_obj(scatter_Id(l)).Visible=T.Active(n_SID);%scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));
                                scatter_obj(scatter_Id(l)).Marker=T.Symbol(n_SID);%scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));
                                scatter_obj(scatter_Id(l)).SizeData=T.SymbolSize(n_SID);%scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));
                                scatter_obj(scatter_Id(l)).MarkerFaceColor=[T.MarkerFaceColor_r(n_SID) T.MarkerFaceColor_g(n_SID) T.MarkerFaceColor_b(n_SID)];%scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));
                                scatter_obj(scatter_Id(l)).MarkerFaceAlpha=T.MarkerFaceAlpha(n_SID);%scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));
                                scatter_obj(scatter_Id(l)).Marker=T.Symbol(n_SID);%scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));
                                scatter_obj(scatter_Id(l)).MarkerEdgeColor=[T.MarkerEdgeColor_r(n_SID) T.MarkerEdgeColor_g(n_SID) T.MarkerEdgeColor_b(n_SID)];%scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));
                                scatter_obj(scatter_Id(l)).MarkerEdgeAlpha=T.MarkerEdgeAlpha(n_SID);%scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));
                                scatter_obj(scatter_Id(l)).LineWidth=T.LineWidth(n_SID);%scatter_obj_org(scatter_Id_org(n_SID)).(char(w2c(k)));

                            end
                        end

                    catch ME
                        0
                    end
                end
            end
            update_ScatterObject_table(app)

        end

        % Button pushed function: ReplaceStringinNameButton
        function ReplaceStringinNameButtonPushed(app, event)


            str2rmv=inputdlg('Enter string to find','Find in SampleName', 1);
            if isempty(str2rmv) || isempty(str2rmv{:})
                       figure(app.EasyPlotModuleUIFigure);
     return
            end
            str2rep=inputdlg('Enter replacement','Replace with in SampleName', 1);
            figure(app.EasyPlotModuleUIFigure);

            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Applying Changes...',...
                'Message','Progress Started','Cancelable','on');

            for n=1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                d.Message = sprintf(['Changing Name: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig))]);
                if d.CancelRequested
                    break
                end

                scatter_obj=flipud(findobj('Parent',app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot,'Type','Scatter'));

                if isempty(scatter_obj)
                    continue
                end
                if any(contains({scatter_obj.DisplayName},str2rmv))
                    scatter_Id=find(contains({scatter_obj.DisplayName},str2rmv));

                    for l=1:numel(scatter_Id)
                        scatter_obj(scatter_Id(l)).DisplayName=replace(scatter_obj(scatter_Id(l)).DisplayName,str2rmv,str2rep);
                    end
                end
            end
            update_ScatterObject_table(app)

        end

        % Button pushed function: xlinlogButton
        function xlinlogButtonPushed(app, event)
            try
                if strcmp(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XScale,'log')
                    state='linear';
                else
                    state='log';

                end
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XScale=state;
            catch ME
                DisplayErrorNG(app.MinPlotX,ME,'xlinlogButtonPushed Update Error')
                CustomAxLimitsCheckBoxValueChanged(app)
            end
        end

        % Button pushed function: ylinlogButton
        function ylinlogButtonPushed(app, event)
            try
                if strcmp(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YScale,'log')
                    state='linear';
                else
                    state='log';

                end
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YScale=state;
            catch ME
                DisplayErrorNG(app.MinPlotX,ME,'ylinlogButtonPushed Update Error')
                CustomAxLimitsCheckBoxValueChanged(app)
            end


        end

        % Button pushed function: xgridButton
        function xgridButtonPushed(app, event)
              try
                if strcmp(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XGrid,'off')
                    state='on';
                else
                    state='off';
                end

                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XMinorGrid=state;
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XGrid=state;
              catch ME
                  DisplayErrorNG(app.MinPlotX,ME,'xgridButtonPushed Update Error')
                  CustomAxLimitsCheckBoxValueChanged(app)
              end
        end

        % Button pushed function: ygridButton
        function ygridButtonPushed(app, event)
            try
                if strcmp(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YGrid,'off')
                    state='on';
                else
                    state='off';
                end

                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YMinorGrid=state;
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YGrid=state;
            catch ME
                DisplayErrorNG(app.MinPlotX,ME,'xgridButtonPushed Update Error')
                CustomAxLimitsCheckBoxValueChanged(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EasyPlotModuleUIFigure = uifigure('Visible', 'off');
            app.EasyPlotModuleUIFigure.Name = 'EasyPlotModule';
            app.GridLayout2 = uigridlayout(app.EasyPlotModuleUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PlotSettingsTab = uitab(app.TabGroup);
            app.PlotSettingsTab.Title = 'PlotSettings';
            app.GridLayout = uigridlayout(app.PlotSettingsTab);
            app.MultiCor_table = uitable(app.GridLayout);
            app.MultiCor_table.ColumnName = {'Active'; 'Name'; 'Symbol'; 'SymbolSize'; 'MarkerFaceColor'};
            app.MultiCor_table.RowName = {};
            app.MultiCor_table.ColumnSortable = [true true true true true false true];
            app.MultiCor_table.ColumnEditable = [true false true true true false false];
            app.MultiCor_table.CellEditCallback = createCallbackFcn(app, @MultiCor_tableCellEdit, true);
            app.MultiCor_table.ButtonDownFcn = createCallbackFcn(app, @MultiCor_tableButtonDown, true);
            app.ColorPreviewCheckBox = uicheckbox(app.GridLayout);
            app.ColorPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorPreviewCheckBoxValueChanged, true);
            app.ColorPreviewCheckBox.Text = 'Color Preview';
            app.AllSettingsCheckBox = uicheckbox(app.GridLayout);
            app.AllSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllSettingsCheckBoxValueChanged, true);
            app.AllSettingsCheckBox.Text = 'All Settings';
            app.RemoveSelectedButton = uibutton(app.GridLayout, 'push');
            app.RemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedButtonPushed, true);
            app.RemoveSelectedButton.Text = 'Remove Selected';
            app.ShowLegendCheckBox = uicheckbox(app.GridLayout);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.LegendInterpreterDropDownLabel = uilabel(app.GridLayout);
            app.LegendInterpreterDropDownLabel.Text = 'Legend Interpreter';
            app.LegendInterpreterDropDown = uidropdown(app.GridLayout);
            app.LegendInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.LegendInterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @LegendInterpreterDropDownValueChanged, true);
            app.LegendInterpreterDropDown.Value = 'none';
            app.FindPlotButton = uibutton(app.GridLayout, 'push');
            app.FindPlotButton.ButtonPushedFcn = createCallbackFcn(app, @FindPlotButtonPushed, true);
            app.FindPlotButton.Text = 'Find Plot';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.GridLayout3 = uigridlayout(app.ExportTab);
            app.ExportAllButton = uibutton(app.GridLayout3, 'push');
            app.ExportAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed, true);
            app.ExportAllButton.Text = 'Export All';
            app.SelectDirButton = uibutton(app.GridLayout3, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = 'Select Dir';
            app.ExportDirectoryEditFieldLabel = uilabel(app.GridLayout3);
            app.ExportDirectoryEditFieldLabel.Text = 'Export Directory';
            app.ExportDirectoryEditField = uieditfield(app.GridLayout3, 'text');
            app.CheckBox_png_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_png_exp.Text = 'png';
            app.CheckBox_pdf_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_pdf_exp.Text = 'pdf';
            app.CheckBox_pdf_exp.Value = true;
            app.CheckBox_eps_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_eps_exp.Text = 'eps';
            app.CheckBox_tif_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_tif_exp.Text = 'tif';
            app.ExportSelectedButton = uibutton(app.GridLayout3, 'push');
            app.ExportSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectedButtonPushed, true);
            app.ExportSelectedButton.Text = 'Export Selected';
            app.ExportSelectionButton = uibutton(app.GridLayout3, 'push');
            app.ExportSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectionButtonPushed, true);
            app.ExportSelectionButton.Text = 'Export Selection';
            app.CheckBox_jpg_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_jpg_exp.Text = 'jpg';
            app.CheckBox_fig_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_fig_exp.Text = 'fig';
            app.CustomStringLabel = uilabel(app.GridLayout3);
            app.CustomStringLabel.Text = 'Custom String';
            app.CustStrEditField = uieditfield(app.GridLayout3, 'text');
            app.Tree = uitree(app.GridLayout2);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UpdateListButton = uibutton(app.GridLayout2, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.CustomAxLimitsCheckBox = uicheckbox(app.GridLayout2);
            app.CustomAxLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomAxLimitsCheckBoxValueChanged, true);
            app.CustomAxLimitsCheckBox.Visible = 'off';
            app.CustomAxLimitsCheckBox.Text = 'Custom Ax Limits';
            app.XLimitsEditFieldLabel = uilabel(app.GridLayout2);
            app.XLimitsEditFieldLabel.Visible = 'off';
            app.XLimitsEditFieldLabel.Text = 'X Limits';
            app.XLimitsEditField_min = uieditfield(app.GridLayout2, 'numeric');
            app.XLimitsEditField_min.ValueChangedFcn = createCallbackFcn(app, @XLimitsEditField_minValueChanged, true);
            app.XLimitsEditField_min.Visible = 'off';
            app.XLimitsEditField_max = uieditfield(app.GridLayout2, 'numeric');
            app.XLimitsEditField_max.ValueChangedFcn = createCallbackFcn(app, @XLimitsEditField_minValueChanged, true);
            app.XLimitsEditField_max.Visible = 'off';
            app.YLimitsEditField_max = uieditfield(app.GridLayout2, 'numeric');
            app.YLimitsEditField_max.ValueChangedFcn = createCallbackFcn(app, @XLimitsEditField_minValueChanged, true);
            app.YLimitsEditField_max.Visible = 'off';
            app.YLimitsEditFieldLabel = uilabel(app.GridLayout2);
            app.YLimitsEditFieldLabel.Visible = 'off';
            app.YLimitsEditFieldLabel.Text = 'Y Limits';
            app.YLimitsEditField_min = uieditfield(app.GridLayout2, 'numeric');
            app.YLimitsEditField_min.ValueChangedFcn = createCallbackFcn(app, @XLimitsEditField_minValueChanged, true);
            app.YLimitsEditField_min.Visible = 'off';
            app.ApplyGlobalButton = uibutton(app.GridLayout2, 'push');
            app.ApplyGlobalButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyGlobalMenuSelected, true);
            app.ApplyGlobalButton.Visible = 'off';
            app.ApplyGlobalButton.Text = 'Apply Global';
            app.ApplyGlobaltoselectionButton = uibutton(app.GridLayout2, 'push');
            app.ApplyGlobaltoselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyGlobaltoselectionMenuSelected, true);
            app.ApplyGlobaltoselectionButton.Visible = 'off';
            app.ApplyGlobaltoselectionButton.Text = 'Apply Global to selection';
            app.CloseallfiguresButton = uibutton(app.GridLayout2, 'push');
            app.CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfiguresButtonPushed, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            app.MergeSelectionButton = uibutton(app.GridLayout2, 'push');
            app.MergeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MergeSelectionButtonPushed, true);
            app.MergeSelectionButton.Visible = 'off';
            app.MergeSelectionButton.Text = 'Merge Selection';
            app.MoveUpButton = uibutton(app.GridLayout2, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Visible = 'off';
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.GridLayout2, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Visible = 'off';
            app.MoveDownButton.Text = 'Move Down';
            app.FlipAllButton = uibutton(app.GridLayout2, 'push');
            app.FlipAllButton.ButtonPushedFcn = createCallbackFcn(app, @FlipAllButtonPushed, true);
            app.FlipAllButton.Visible = 'off';
            app.FlipAllButton.Text = 'Flip All';
            app.FlipSelectionButton = uibutton(app.GridLayout2, 'push');
            app.FlipSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @FlipSelectionButtonPushed, true);
            app.FlipSelectionButton.Visible = 'off';
            app.FlipSelectionButton.Text = 'Flip Selection';
            app.ReadStyleButton = uibutton(app.GridLayout2, 'push');
            app.ReadStyleButton.ButtonPushedFcn = createCallbackFcn(app, @ReadStyleButtonPushed, true);
            app.ReadStyleButton.Visible = 'off';
            app.ReadStyleButton.Text = 'Read Style';
            app.ExportStyleButton = uibutton(app.GridLayout2, 'push');
            app.ExportStyleButton.ButtonPushedFcn = createCallbackFcn(app, @ExportStyleButtonPushed, true);
            app.ExportStyleButton.Visible = 'off';
            app.ExportStyleButton.Text = 'Export Style';
            app.ReplaceStringinNameButton = uibutton(app.GridLayout2, 'push');
            app.ReplaceStringinNameButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceStringinNameButtonPushed, true);
            app.ReplaceStringinNameButton.Visible = 'off';
            app.ReplaceStringinNameButton.Text = 'Replace String in Name';
            app.xlinlogButton = uibutton(app.GridLayout2, 'push');
            app.xlinlogButton.ButtonPushedFcn = createCallbackFcn(app, @xlinlogButtonPushed, true);
            app.xlinlogButton.Visible = 'off';
            app.xlinlogButton.Text = 'x lin/log';
            app.ylinlogButton = uibutton(app.GridLayout2, 'push');
            app.ylinlogButton.ButtonPushedFcn = createCallbackFcn(app, @ylinlogButtonPushed, true);
            app.ylinlogButton.Visible = 'off';
            app.ylinlogButton.Text = 'y lin/log';
            app.xgridButton = uibutton(app.GridLayout2, 'push');
            app.xgridButton.ButtonPushedFcn = createCallbackFcn(app, @xgridButtonPushed, true);
            app.xgridButton.Visible = 'off';
            app.xgridButton.Text = 'x grid';
            app.ygridButton = uibutton(app.GridLayout2, 'push');
            app.ygridButton.ButtonPushedFcn = createCallbackFcn(app, @ygridButtonPushed, true);
            app.ygridButton.Visible = 'off';
            app.ygridButton.Text = 'y grid';
            % Show the figure after all components are created
            app.EasyPlotModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EasyPlotModule(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EasyPlotModuleUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EasyPlotModuleUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyPlotModuleUIFigure)
        end
    end
end